classdef appProt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        DateEditField                  matlab.ui.control.NumericEditField
        DateEditFieldLabel             matlab.ui.control.Label
        PeriodSpinner                  matlab.ui.control.Spinner
        PeriodSpinnerLabel             matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        SelectroutetoexportfileButton  matlab.ui.control.Button
        ColorEditField                 matlab.ui.control.NumericEditField
        ColorEditFieldLabel            matlab.ui.control.Label
        ORPEditField                   matlab.ui.control.NumericEditField
        ORPEditFieldLabel              matlab.ui.control.Label
        DOEditField                    matlab.ui.control.NumericEditField
        DOEditFieldLabel               matlab.ui.control.Label
        SalinityEditField              matlab.ui.control.NumericEditField
        SalinityEditFieldLabel         matlab.ui.control.Label
        TSSEditField                   matlab.ui.control.NumericEditField
        TSSEditFieldLabel              matlab.ui.control.Label
        ConductivityEditField          matlab.ui.control.NumericEditField
        ConductivityEditFieldLabel     matlab.ui.control.Label
        pHEditField                    matlab.ui.control.NumericEditField
        pHEditFieldLabel               matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        SerialPortDropDown             matlab.ui.control.DropDown
        SerialPortDropDownLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxesColor                    matlab.ui.control.UIAxes
        UIAxesORP                      matlab.ui.control.UIAxes
        UIAxesSalinity                 matlab.ui.control.UIAxes
        UIAxesDO                       matlab.ui.control.UIAxes
        UIAxesTSS                      matlab.ui.control.UIAxes
        UIAxesConductivity             matlab.ui.control.UIAxes
        UIAxespH                       matlab.ui.control.UIAxes
        UIAxesTemperature              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        startTime % Description
        timer % Description
        serialPort % Description
    end
    
    methods (Access = private)
            function readSerialData(app,src,~)
                global file route f
                
                if (strcmp(app.timer.Running, 'off'))
                    configureCallback(src,"off");
                    disp("STOPPED")
                    return
                end
                
                data = readline(src);
                disp("READ")
                data=split(data,'/');
                
                T=datetime("now","Format","dd/MMM/uuuu HH:mm:ss.SSS");
                T=datestr(T);
                T=split(T);
                
                data(9)=T(1);
                data(10)=T(2);
                
                src.UserData = data;
        
                Temperature=data(1);
                pH=data(2);
                Conductivity=data(3);
                TSS=data(4);
                DO=data(5);
                Salinity=data(6);
                ORP=data(7);
                Color=data(8);
                
                data=table(Temperature,pH,Conductivity,TSS,DO,Salinity,ORP,Color,data(9),data(10));
                writetable(data,fullfile(route,file),"WriteMode","append","WriteVariableNames",false);
                disp("WRITTEN")
                f=1;
            end
        
            function graph(app,~)
                global f i
                persistent t v
                
                if(isempty(t))
                    t=NaT(1,60,"Format","HH:mm:ss");%datetime.empty;
                end
                if(isempty(v))
                v=zeros(8,60);
                end
                
                if(isempty(i))
                i=1;
                elseif(i==60)
                i=1;
                t=NaT(1,60,"Format","HH:mm:ss");
                v=zeros(8,60);
                end
                
                if(f)
                    disp("CHANGED")
                    f=0;
                    
                    t(i)=datetime(app.serialPort.UserData(10),"InputFormat","HH:mm:ss","Format","HH:mm:ss"); %t(end+1)=seconds(datetime(serialPort.UserData(10),"InputFormat","HH:mm:ss","Format","HH:mm:ss")-app.startTime);
    
                    v(1,i)=str2double(app.serialPort.UserData(1));
                    plot(app.UIAxesTemperature,t,v(1,:),"-o","Color","blue")
                    
                    v(2,i)=str2double(app.serialPort.UserData(2));
                    plot(app.UIAxespH,t,v(2,:),"-o","Color","blue")
                    
                    v(3,i)=str2double(app.serialPort.UserData(3));
                    plot(app.UIAxesConductivity,t,v(3,:),"-o","Color","blue")
                    
                    v(4,i)=str2double(app.serialPort.UserData(4));
                    %hold(app.UIAxesTSS,"on");
                    plot(app.UIAxesTSS,t,v(4,:),"-o","Color","blue")
                    %hold(app.UIAxesTSS,"off");
                    
                    v(5,i)=str2double(app.serialPort.UserData(5));
                    %hold(app.UIAxesDO,"on");
                    plot(app.UIAxesDO,t,v(5,:),"-o","Color","blue")
                    %hold(app.UIAxesDO,"off");
                    
                    v(6,i)=str2double(app.serialPort.UserData(6));
                    %hold(app.UIAxesSalinity,"on");
                    plot(app.UIAxesSalinity,t,v(6,:),"-o","Color","blue")
                    %hold(app.UIAxesSalinity,"off");
                    
                    v(7,i)=str2double(app.serialPort.UserData(7));
                    %hold(app.UIAxesORP,"on");
                    plot(app.UIAxesORP,t,v(7,:),"-o","Color","blue")
                    %hold(app.UIAxesORP,"off");
                    
                    v(8,i)=str2double(app.serialPort.UserData(8));
                    %hold(app.UIAxesColor,"on");
                    plot(app.UIAxesColor,t,v(8,:),"-o","Color","blue")
                    %hold(app.UIAxesColor,"off");
                    
                    i=i+1;
                end
                
            end
    end
    
    methods (Access = public)
               
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global val prevPath file period
            file="";
            period=app.PeriodSpinner.Value;
            app.SerialPortDropDown.Items=["Select",serialportlist];
            val=app.SerialPortDropDown.Value;
            prevPath=pwd;
            app.startTime=datetime("now","Format","dd/MMM/uuuu HH:mm:ss.SSS");
            
%             t = app.startTime + seconds(1:60);
%             xLL = t(1) - seconds(5);
%             xUL = t(length(t));
%             app.UIAxesTemperature.XLim = [xLL xUL];
%             app.UIAxesTemperature.XAxis.TickLabelFormat = 'hh:mm:ss';
%             app.UIAxesTemperature.XTick = linspace(xLL, xUL, 5);
            
            app.UIAxesTemperature.NextPlot='add';
            app.UIAxespH.NextPlot='add';
            app.UIAxesConductivity.NextPlot='add';
            app.UIAxesTSS.NextPlot='add';
            app.UIAxesDO.NextPlot='add';
            app.UIAxesSalinity.NextPlot='add';
            app.UIAxesORP.NextPlot='add';
            app.UIAxesColor.NextPlot='add';
            
            plot(app.UIAxesTemperature,app.startTime,NaN)
            plot(app.UIAxespH,app.startTime,NaN)
            plot(app.UIAxesConductivity,app.startTime,NaN)
            plot(app.UIAxesTSS,app.startTime,NaN)
            plot(app.UIAxesDO,app.startTime,NaN)
            plot(app.UIAxesSalinity,app.startTime,NaN)
            plot(app.UIAxesORP,app.startTime,NaN)
            plot(app.UIAxesColor,app.startTime,NaN)
            
            app.timer = timer(...
            'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
            'Period', 0.1, ...                     % Period is 20 ms
            'BusyMode', 'queue',...              % Queue timer callbacks when busy
            'TimerFcn', @(varargin)app.graph());      % Specify callback function
        end

        % Button pushed function: SelectroutetoexportfileButton
        function SelectroutetoexportfileButtonPushed(app, event)
            global route file

            [file,route] = uiputfile('*.csv');
            
            if isequal(file,0)
                file="";
                uialert(app.UIFigure,'User clicked Cancel.','Info','Icon','info');
                drawnow;
                figure(app.UIFigure);
            else
                uialert(app.UIFigure,['User selected ',fullfile(route,file),...
                ' and then clicked Save.'],'Success','Icon','success');
                drawnow;
                figure(app.UIFigure);
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global route file dataR val period


            if isequal(app.SerialPortDropDown.Value,"Select")
                uialert(app.UIFigure,'Serial port not connected','Error','Icon','error');
                drawnow;
                figure(app.UIFigure);
            else
                %
                try
                    app.serialPort=serialport(val,9600,"Timeout",period);
                catch ME
                    msg=getReport(ME,'basic','hyperlinks','off');
                    uialert(app.UIFigure,["Error ",msg],"Error","Icon","error");
                    %
                end
                if (strcmp(file,""))
                    file="tableData.csv";
                    route=pwd;
                    disp("true")
                else
                    disp("false")
                    disp(file)
                end
                    configureCallback(app.serialPort,"terminator",@app.readSerialData);
                    configureTerminator(app.serialPort,255);
                    flush(app.serialPort);
                    
                if(~isfile(fullfile(route,file)))
                    dataR=table("Temperature","pH","Conductivity","TSS","DO","Salinity","ORP","Color","Date","Time");
                    writetable(dataR,fullfile(route,file),"WriteMode","append","WriteVariableNames",false);
                end

                if (strcmp(app.timer.Running, 'off'))
                    start(app.timer);
                end
                
            end
            
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
                global i
                stop(app.timer);
                i=60;
                disp("STOPPED")
                flush(app.serialPort);
                app.serialPort=[];
                disp("DELETED/CLEARED")
        end

        % Value changed function: SerialPortDropDown
        function SerialPortDropDownValueChanged(app, event)
            global val
            val = app.SerialPortDropDown.Value;
        end

        % Callback function
        function PeriodEditFieldValueChanged(app, event)
            %global period
            %period = app.PeriodEditField.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            startupFcn(app);
        end

        % Value changed function: PeriodSpinner
        function PeriodSpinnerValueChanged(app, event)
            global period
            period = app.PeriodSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesTemperature = uiaxes(app.UIFigure);
            title(app.UIAxesTemperature, 'Temperature')
            xlabel(app.UIAxesTemperature, 'Time')
            ylabel(app.UIAxesTemperature, 'C°')
            zlabel(app.UIAxesTemperature, 'Z')
            app.UIAxespH = uiaxes(app.UIFigure);
            title(app.UIAxespH, 'pH')
            xlabel(app.UIAxespH, 'Time')
            ylabel(app.UIAxespH, 'Y')
            zlabel(app.UIAxespH, 'Z')
            app.UIAxesConductivity = uiaxes(app.UIFigure);
            title(app.UIAxesConductivity, 'Conductivity')
            xlabel(app.UIAxesConductivity, 'Time')
            ylabel(app.UIAxesConductivity, 'Y')
            zlabel(app.UIAxesConductivity, 'Z')
            app.UIAxesTSS = uiaxes(app.UIFigure);
            title(app.UIAxesTSS, 'Total Suspended Solids')
            xlabel(app.UIAxesTSS, 'Time')
            ylabel(app.UIAxesTSS, 'Y')
            zlabel(app.UIAxesTSS, 'Z')
            app.UIAxesDO = uiaxes(app.UIFigure);
            title(app.UIAxesDO, 'Dissolved Oxygen')
            xlabel(app.UIAxesDO, 'Time')
            ylabel(app.UIAxesDO, 'Y')
            zlabel(app.UIAxesDO, 'Z')
            app.UIAxesSalinity = uiaxes(app.UIFigure);
            title(app.UIAxesSalinity, 'Salinity')
            xlabel(app.UIAxesSalinity, 'Time')
            ylabel(app.UIAxesSalinity, 'Y')
            zlabel(app.UIAxesSalinity, 'Z')
            app.UIAxesORP = uiaxes(app.UIFigure);
            title(app.UIAxesORP, 'Oxidation Reduction Potential')
            xlabel(app.UIAxesORP, 'Time')
            ylabel(app.UIAxesORP, 'Y')
            zlabel(app.UIAxesORP, 'Z')
            app.UIAxesColor = uiaxes(app.UIFigure);
            title(app.UIAxesColor, 'Color')
            xlabel(app.UIAxesColor, 'Time')
            ylabel(app.UIAxesColor, 'Y')
            zlabel(app.UIAxesColor, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UDG.png';
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {'Select', 'Option 2', 'Option 3', 'Option 4'};
            app.SerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDownValueChanged, true);
            app.SerialPortDropDown.Value = 'Select';
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.Editable = 'off';
            app.pHEditFieldLabel = uilabel(app.UIFigure);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHEditField = uieditfield(app.UIFigure, 'numeric');
            app.pHEditField.Editable = 'off';
            app.ConductivityEditFieldLabel = uilabel(app.UIFigure);
            app.ConductivityEditFieldLabel.Text = 'Conductivity';
            app.ConductivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConductivityEditField.Editable = 'off';
            app.TSSEditFieldLabel = uilabel(app.UIFigure);
            app.TSSEditFieldLabel.Text = 'TSS';
            app.TSSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TSSEditField.Editable = 'off';
            app.SalinityEditFieldLabel = uilabel(app.UIFigure);
            app.SalinityEditFieldLabel.Text = 'Salinity';
            app.SalinityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SalinityEditField.Editable = 'off';
            app.DOEditFieldLabel = uilabel(app.UIFigure);
            app.DOEditFieldLabel.Text = 'DO';
            app.DOEditField = uieditfield(app.UIFigure, 'numeric');
            app.DOEditField.Editable = 'off';
            app.ORPEditFieldLabel = uilabel(app.UIFigure);
            app.ORPEditFieldLabel.Text = 'ORP';
            app.ORPEditField = uieditfield(app.UIFigure, 'numeric');
            app.ORPEditField.Editable = 'off';
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorEditField.Editable = 'off';
            app.SelectroutetoexportfileButton = uibutton(app.UIFigure, 'push');
            app.SelectroutetoexportfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectroutetoexportfileButtonPushed, true);
            app.SelectroutetoexportfileButton.Text = 'Select route to export file';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'refresh_icon.jpg';
            app.Button.Text = '';
            app.PeriodSpinnerLabel = uilabel(app.UIFigure);
            app.PeriodSpinnerLabel.Text = 'Period';
            app.PeriodSpinner = uispinner(app.UIFigure);
            app.PeriodSpinner.Limits = [60 120];
            app.PeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @PeriodSpinnerValueChanged, true);
            app.PeriodSpinner.Value = 60;
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.UIFigure, 'numeric');
            app.DateEditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appProt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end